classdef aula1_app_2018 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        bSliderLabel  matlab.ui.control.Label
        bSlider       matlab.ui.control.Slider
        UIAxes        matlab.ui.control.UIAxes
        Appusadoparaaatividade2daaula1Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            vb = app.bSlider.Value;
            m=evalin("base",'m');
            k=evalin("base",'k');
            b=2*vb*sqrt(k*m);
            g=tf(1,[m b k]);
            [y,t]=step(g);
            plot(app.UIAxes,t,y);title('Resposta ao degrau');
            grid(app.UIAxes,'on');
            imshow('mma.jpg');title('Sistema massa-mola-amortecedor');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [0.1 1];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.Value = 0.1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Tempo(s)')
            ylabel(app.UIAxes, 'Y')
            app.Appusadoparaaatividade2daaula1Label = uilabel(app.UIFigure);
            app.Appusadoparaaatividade2daaula1Label.Text = 'App usado para a atividade 2 da aula 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aula1_app_2018

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end